classdef HTMLWebApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure   matlab.ui.Figure
        MainLayout   matlab.ui.container.GridLayout
        GridLayout   matlab.ui.container.GridLayout
        ScrapeHTML   matlab.ui.control.HTML
        MainPanel    matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        Image        matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function setupHTMLComponent(app)
            % SETUPHTMLCOMPONENT Method to set up the properties of the
            % UIHTML component.

            % Set HTML Data
            app.ScrapeHTML.Data = jsonencode("Hello");

            % Assign HTMLPath
            htmlPath = which(fullfile("+html/URLScraperHTML.html"));
            app.ScrapeHTML.HTMLSource = htmlPath;
            
        end %function

    end %method
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set up HTML Component
            app.setupHTMLComponent();

            % Create App Debugger
            assignin("base","app",app);
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainLayout = uigridlayout(app.MainFigure);
            app.MainPanel = uipanel(app.MainLayout);
            app.MainPanel.Title = 'Interface Panel';
            app.GridLayout2 = uigridlayout(app.MainPanel);
            app.Image = uiimage(app.GridLayout2);
            app.GridLayout = uigridlayout(app.MainLayout);
            app.ScrapeHTML = uihtml(app.GridLayout);
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HTMLWebApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MainFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MainFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end